classdef adohe_fototoko_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup2                  matlab.ui.container.TabGroup
        ImageTab                   matlab.ui.container.Tab
        GridLayout5                matlab.ui.container.GridLayout
        InputImage                 matlab.ui.control.Image
        OutputImage                matlab.ui.control.Image
        HistogramTab_2             matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        OutputLabel                matlab.ui.control.Label
        InputLabel                 matlab.ui.control.Label
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        SpecificationHistogramTab  matlab.ui.container.Tab
        GridLayout4_2              matlab.ui.container.GridLayout
        OutputLabel_2              matlab.ui.control.Label
        TargetLabel                matlab.ui.control.Label
        SpecUIAxesG                matlab.ui.control.UIAxes
        SpecUIAxesB                matlab.ui.control.UIAxes
        OutSpecUIAxesB             matlab.ui.control.UIAxes
        OutSpecUIAxesG             matlab.ui.control.UIAxes
        OutSpecUIAxesR             matlab.ui.control.UIAxes
        SpecUIAxesR                matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        OpenFileTab                matlab.ui.container.Tab
        GridLayout6_2              matlab.ui.container.GridLayout
        ChooseImageButton          matlab.ui.control.Button
        NoFileSelectedLabel        matlab.ui.control.Label
        HistogramTab               matlab.ui.container.Tab
        GridLayout6                matlab.ui.container.GridLayout
        ProcessButton_3            matlab.ui.control.Button
        KonvolusiTab               matlab.ui.container.Tab
        GridLayout7                matlab.ui.container.GridLayout
        x7GaussianMaskLabel        matlab.ui.control.Label
        x33Label                   matlab.ui.control.Label
        x32Label                   matlab.ui.control.Label
        x31Label                   matlab.ui.control.Label
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        ChooseMaskButtonGroup      matlab.ui.container.ButtonGroup
        x7GaussianMaskButton       matlab.ui.control.RadioButton
        x33Button                  matlab.ui.control.RadioButton
        x32Button                  matlab.ui.control.RadioButton
        x31Button                  matlab.ui.control.RadioButton
        ApplyButton                matlab.ui.control.Button
        ProcessButton_4            matlab.ui.control.Button
    end

    
    properties (Access = private)
        Fullimagename % Full image name
        Inputimagedata % Image data (input)
        Outputimagedata % Image data (output)
        SpecTargetImagedata % Description
        SpecTargetFullImagename % Description
    end
    
    methods (Access = private)
        
        function setHistogram(~, imagedata, UIImage, RAxes, GAxes, BAxes)
            % clear histogram
            cla(RAxes);
            cla(GAxes);
            cla(BAxes);
            title(RAxes, '');
            title(GAxes, '');
            title(BAxes, '');

            % handle rgb image
            numChannel = size(imagedata, 3);
            if numChannel == 3
                % Display the image in the ui image component
                UIImage.ImageSource = imagedata;
            
                % Separate the RGB channels
                redChannel = imagedata(:, :, 1);
                greenChannel = imagedata(:, :, 2);
                blueChannel = imagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(RAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(RAxes, 'on');
                bar(GAxes, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(GAxes, 'on');
                bar(BAxes, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(BAxes, 'on');
                
                % Set titles for each axes
                title(RAxes, 'Red Channel Histogram');
                title(GAxes, 'Green Channel Histogram');
                title(BAxes, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(RAxes, [0, max(redHistogram)]); 
                ylim(GAxes, [0, max(greenHistogram)]);
                ylim(BAxes, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(RAxes, [0, 256]);
                xlim(GAxes, [0, 256]);
                xlim(BAxes, [0, 256]);

                % Turn off hold for all axes
                hold(RAxes, 'off');
                hold(GAxes, 'off');
                hold(BAxes, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, imagedata(:, :, 1),imagedata(:, :, 1),imagedata(:, :, 1));
                % Display the image in the uiimage component
                UIImage.ImageSource = rgbimage;
                
                % grey channel
                greyChannel = imagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(RAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(RAxes, 'on');
                
                % Set the limit to the maximum value
                ylim(RAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(RAxes, [0, 256]);
                
                % Set a title for the axis
                title(RAxes, 'Grayscale Image Histogram');
                hold(RAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end

        function setHistogramInput(app, numChannel)
            
            % clear histogram
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            title(app.UIAxes, '');
            title(app.UIAxes2, '');
            title(app.UIAxes3, '');

            % handle rgb image
            if numChannel == 3
                % Display the image in the ui image component
                app.InputImage.ImageSource = app.Inputimagedata;
            
                % Separate the RGB channels
                redChannel = app.Inputimagedata(:, :, 1);
                greenChannel = app.Inputimagedata(:, :, 2);
                blueChannel = app.Inputimagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes, 'on');
                bar(app.UIAxes2, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes2, 'on');
                bar(app.UIAxes3, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes3, 'on');
                
                % Set titles for each axes
                title(app.UIAxes, 'Red Channel Histogram');
                title(app.UIAxes2, 'Green Channel Histogram');
                title(app.UIAxes3, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(redHistogram)]); 
                ylim(app.UIAxes2, [0, max(greenHistogram)]);
                ylim(app.UIAxes3, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                xlim(app.UIAxes2, [0, 256]);
                xlim(app.UIAxes3, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes, 'off');
                hold(app.UIAxes2, 'off');
                hold(app.UIAxes3, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1));
                % Display the image in the uiimage component
                app.InputImage.ImageSource = rgbimage;
                
                % grey channel
                greyChannel = app.Inputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(app.UIAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes, 'on');
                
                disp(greyHistogram)
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                
                % Set a title for the axis
                title(app.UIAxes, 'Grayscale Image Histogram');
                hold(app.UIAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end
        
        function setHistogramOutput(app, numChannel)
            % clear histogram
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            title(app.UIAxes4, '');
            title(app.UIAxes5, '');
            title(app.UIAxes6, '');
            
            % handle rgb image
            if numChannel == 3
                % Display the image in the uiimage component
                app.OutputImage.ImageSource = app.Outputimagedata;
            
                % Separate the RGB channels
                redChannel = app.Outputimagedata(:, :, 1);
                greenChannel = app.Outputimagedata(:, :, 2);
                blueChannel = app.Outputimagedata(:, :, 3);
        
                % set value histogram
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);
                
                % calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes4, 'on');
                bar(app.UIAxes5, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes5, 'on');
                bar(app.UIAxes6, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes6, 'on');
    
                % Set titles for each axes
                title(app.UIAxes4, 'Red Channel Histogram');
                title(app.UIAxes5, 'Green Channel Histogram');
                title(app.UIAxes6, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(redHistogram)]); 
                ylim(app.UIAxes5, [0, max(greenHistogram)]);
                ylim(app.UIAxes6, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);
                xlim(app.UIAxes5, [0, 256]);
                xlim(app.UIAxes6, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes4, 'off');
                hold(app.UIAxes5, 'off');
                hold(app.UIAxes6, 'off');

            % handle grayscale image
            elseif numChannel == 1
                % convert grayscale to format rgb to show image
                rgbimage = cat(3, app.Outputimagedata,app.Outputimagedata,app.Outputimagedata);

                % Display the image in the uiimage component
                app.OutputImage.ImageSource = rgbimage;
            
                % grey channel
                greyChannel = app.Outputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes4, 'on');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);

                % Set a title for the axis
                title(app.UIAxes4, 'Grayscale Image Histogram');
                hold(app.UIAxes4, 'off');
            end
        end
            

        function setText(app, label)
            % set text label
            app.NoFileSelectedLabel.Text = label;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function apply(app, event)
            % Apply so that output image -> input image
            app.Inputimagedata = app.Outputimagedata;
            
            % set histogram and image output
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramInput(numChannels);
        end

        % Callback function: ChooseImageButton, OpenMenu
        function chooseFileHandler(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.jpeg;*.tif;*.bmp', 'Image Files (*.jpg,*.png,*.jpeg,*.tif,*.bmp)'}, 'Select an image file');
            
            if isequal(file, 0) || isequal(path, 0)
               % Handle the case when the user cancels the file selection
               app.NoFileSelectedLabel.Text = 'No file selected';
               return; % Exit the function
            end
            

            % Create the full file path
            fullFilePath = fullfile(path, file);
            % [ ~ , ~ , ext ] = fileparts( fullFilePath );
  
            % Display the image in the app.SpecImage component
            % Load the BMP image
            bmpImage = imread(fullFilePath);
        
            app.Fullimagename = fullFilePath;
            app.Inputimagedata = bmpImage; 
            app.Outputimagedata = bmpImage;
            % Convert BMP to PNG format
            % You may need to adjust the filename and path as needed
            pngFileName = 'converted_image.png';
            pngFilePath = fullfile(path, pngFileName);
            imwrite(bmpImage, pngFilePath);
            
          
            numChannels = size(bmpImage, 3);

            % set all variable
            app.setText(file)
            app.setHistogramInput(numChannels);
        end

        % Button pushed function: ProcessButton_3
        function processHistogram(app, event)
            numChannels = size(app.Inputimagedata, 3);
            app.setHistogramInput(numChannels);
        end

        % Button pushed function: ProcessButton_4
        function processKonvolusiCitra(app, event)

            if (app.x7GaussianMaskButton.Value == true)
                mask = [
                    1/140,1/140,2/140,2/140,2/140,1/140,1/140;
                    1/140,2/140,2/140,4/140,2/140,2/140,1/140;
                    2/140,2/140,4/140,8/140,4/140,2/140,2/140;
                    2/140,4/140,8/140,16/140,8/140,4/140,2/140;
                    2/140,2/140,4/140,8/140,4/140,2/140,2/140;
                    1/140,2/140,2/140,4/140,2/140,2/140,1/140;
                    1/140,1/140,2/140,2/140,2/140,1/140,1/140;
                    ];
            elseif (app.x31Button.Value == true)
                mask = [1/16, 2/16, 2/16; 2/16, 4/16, 2/16; 1/16, 2/16, 1/16];
            elseif (app.x32Button.Value == true)
                mask = [0, -1, 0; -1, 4, -1; 0, -1, 0];
            elseif (app.x33Button.Value == true)
                mask = [-1, -1, -1; -1, 17, -1; -1, -1, -1];
            else 
                mask = randi([1, 5], 3, 3);
            end
            
            disp(mask);
            app.Outputimagedata = uint8(konvolusiCitra(double(app.Inputimagedata), mask,1,1));
            
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.Tooltip = {'Histogram'};
            app.OpenFileTab = uitab(app.TabGroup);
            app.OpenFileTab.Title = 'Open File';
            app.GridLayout6_2 = uigridlayout(app.OpenFileTab);
            app.NoFileSelectedLabel = uilabel(app.GridLayout6_2);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            app.ChooseImageButton = uibutton(app.GridLayout6_2, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout6 = uigridlayout(app.HistogramTab);
            app.ProcessButton_3 = uibutton(app.GridLayout6, 'push');
            app.ProcessButton_3.ButtonPushedFcn = createCallbackFcn(app, @processHistogram, true);
            app.ProcessButton_3.Text = 'Process';
            app.KonvolusiTab = uitab(app.TabGroup);
            app.KonvolusiTab.Title = 'Konvolusi';
            app.GridLayout7 = uigridlayout(app.KonvolusiTab);
            app.ProcessButton_4 = uibutton(app.GridLayout7, 'push');
            app.ProcessButton_4.ButtonPushedFcn = createCallbackFcn(app, @processKonvolusiCitra, true);
            app.ProcessButton_4.Text = 'Process';
            app.ApplyButton = uibutton(app.GridLayout7, 'push');
            app.ApplyButton.Text = 'Apply';
            app.ChooseMaskButtonGroup = uibuttongroup(app.GridLayout7);
            app.ChooseMaskButtonGroup.Title = 'Choose Mask';
            app.x31Button = uiradiobutton(app.ChooseMaskButtonGroup);
            app.x31Button.Text = '3 x 3 (1)';
            app.x31Button.Value = true;
            app.x32Button = uiradiobutton(app.ChooseMaskButtonGroup);
            app.x32Button.Text = '3 x 3 (2)';
            app.x33Button = uiradiobutton(app.ChooseMaskButtonGroup);
            app.x33Button.Text = '3 x 3 (3)';
            app.x7GaussianMaskButton = uiradiobutton(app.ChooseMaskButtonGroup);
            app.x7GaussianMaskButton.Text = '7 x 7 Gaussian Mask';
            app.Image = uiimage(app.GridLayout7);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'AssetGUI', 'Screenshot 2023-09-30 154001.png');
            app.Image2 = uiimage(app.GridLayout7);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'AssetGUI', '2.png');
            app.Image3 = uiimage(app.GridLayout7);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'AssetGUI', '3.png');
            app.Image4 = uiimage(app.GridLayout7);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'AssetGUI', '4.png');
            app.x31Label = uilabel(app.GridLayout7);
            app.x31Label.Text = '3 x 3 (1)';
            app.x32Label = uilabel(app.GridLayout7);
            app.x32Label.Text = '3 x 3 (2)';
            app.x33Label = uilabel(app.GridLayout7);
            app.x33Label.Text = '3 x 3 (3)';
            app.x7GaussianMaskLabel = uilabel(app.GridLayout7);
            app.x7GaussianMaskLabel.Text = '7 x 7 Gaussian Mask';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.GridLayout5 = uigridlayout(app.ImageTab);
            app.OutputImage = uiimage(app.GridLayout5);
            app.InputImage = uiimage(app.GridLayout5);
            app.HistogramTab_2 = uitab(app.TabGroup2);
            app.HistogramTab_2.Title = 'Histogram';
            app.GridLayout4 = uigridlayout(app.HistogramTab_2);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout4);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.InputLabel = uilabel(app.GridLayout4);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.GridLayout4);
            app.OutputLabel.Text = 'Output';
            app.SpecificationHistogramTab = uitab(app.TabGroup2);
            app.SpecificationHistogramTab.Title = 'Specification Histogram';
            app.GridLayout4_2 = uigridlayout(app.SpecificationHistogramTab);
            app.SpecUIAxesR = uiaxes(app.GridLayout4_2);
            title(app.SpecUIAxesR, 'Title')
            xlabel(app.SpecUIAxesR, 'X')
            ylabel(app.SpecUIAxesR, 'Y')
            zlabel(app.SpecUIAxesR, 'Z')
            app.OutSpecUIAxesR = uiaxes(app.GridLayout4_2);
            title(app.OutSpecUIAxesR, 'Title')
            xlabel(app.OutSpecUIAxesR, 'X')
            ylabel(app.OutSpecUIAxesR, 'Y')
            zlabel(app.OutSpecUIAxesR, 'Z')
            app.OutSpecUIAxesG = uiaxes(app.GridLayout4_2);
            title(app.OutSpecUIAxesG, 'Title')
            xlabel(app.OutSpecUIAxesG, 'X')
            ylabel(app.OutSpecUIAxesG, 'Y')
            zlabel(app.OutSpecUIAxesG, 'Z')
            app.OutSpecUIAxesB = uiaxes(app.GridLayout4_2);
            title(app.OutSpecUIAxesB, 'Title')
            xlabel(app.OutSpecUIAxesB, 'X')
            ylabel(app.OutSpecUIAxesB, 'Y')
            zlabel(app.OutSpecUIAxesB, 'Z')
            app.SpecUIAxesB = uiaxes(app.GridLayout4_2);
            title(app.SpecUIAxesB, 'Title')
            xlabel(app.SpecUIAxesB, 'X')
            ylabel(app.SpecUIAxesB, 'Y')
            zlabel(app.SpecUIAxesB, 'Z')
            app.SpecUIAxesG = uiaxes(app.GridLayout4_2);
            title(app.SpecUIAxesG, 'Title')
            xlabel(app.SpecUIAxesG, 'X')
            ylabel(app.SpecUIAxesG, 'Y')
            zlabel(app.SpecUIAxesG, 'Z')
            app.TargetLabel = uilabel(app.GridLayout4_2);
            app.TargetLabel.Text = 'Target';
            app.OutputLabel_2 = uilabel(app.GridLayout4_2);
            app.OutputLabel_2.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adohe_fototoko_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end